classdef AMCFOC7_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIFigureGrid                    matlab.ui.container.GridLayout
        ToolstripPanel                  matlab.ui.container.Panel
        RunOnTarget                     matlab.ui.container.Panel
        StopTimeEditField               slrealtime.ui.control.StopTimeEditField
        StopTimeLabel                   matlab.ui.control.Label
        StartStopButton                 slrealtime.ui.control.StartStopButton
        SystemLogPanel                  matlab.ui.container.Panel
        SystemLog                       slrealtime.ui.control.SystemLog
        Prepare                         matlab.ui.container.Panel
        LoadButton                      slrealtime.ui.control.LoadButton
        ConnectToTarget                 matlab.ui.container.Panel
        ConnectToTargetGrid             matlab.ui.container.GridLayout
        TargetSelector                  slrealtime.ui.control.TargetSelector
        ConnectButton                   slrealtime.ui.control.ConnectButton
        TabGroup                        matlab.ui.container.TabGroup
        CANParametersTab                matlab.ui.container.Tab
        MotorConfigurationPanel         matlab.ui.container.Panel
        PolepairsLabel                  matlab.ui.control.Label
        RotorindexoffsetLabel           matlab.ui.control.Label
        EncoderresLabel                 matlab.ui.control.Label
        EncodertolLabel                 matlab.ui.control.Label
        rotor_encoder_resolution_Value  matlab.ui.control.NumericEditField
        pole_pairs_Value                matlab.ui.control.NumericEditField
        rotor_index_offset_Value        matlab.ui.control.NumericEditField
        encoder_tolerance_Value         matlab.ui.control.NumericEditField
        enable_verbosity_Value          matlab.ui.control.CheckBox
        use_index_Value                 matlab.ui.control.CheckBox
        has_temperature_sens_Value      matlab.ui.control.CheckBox
        has_quadrature_encoder_Value    matlab.ui.control.CheckBox
        has_hall_sens_Value             matlab.ui.control.CheckBox
        CurrentPIDPanel                 matlab.ui.container.Panel
        KDLabel_2                       matlab.ui.control.Label
        KILabel_2                       matlab.ui.control.Label
        KPLabel_2                       matlab.ui.control.Label
        OutMaxLabel_2                   matlab.ui.control.Label
        OutMinLabel_2                   matlab.ui.control.Label
        CurPID_shiftfactor_Value        matlab.ui.control.NumericEditField
        curPID_I_Value                  matlab.ui.control.NumericEditField
        curPID_P_Value                  matlab.ui.control.NumericEditField
        curPID_OutMax_Value             matlab.ui.control.NumericEditField
        curPID_OutMin_Value             matlab.ui.control.NumericEditField
        VelocityPIDPanel                matlab.ui.container.Panel
        KDLabel                         matlab.ui.control.Label
        KILabel                         matlab.ui.control.Label
        KPLabel                         matlab.ui.control.Label
        ShiftFactorLabel                matlab.ui.control.Label
        OutMaxLabel                     matlab.ui.control.Label
        OutMinLabel                     matlab.ui.control.Label
        velPID_OutMax_Value             matlab.ui.control.NumericEditField
        velPID_OutMin_Value             matlab.ui.control.NumericEditField
        velPID_P_Value                  matlab.ui.control.NumericEditField
        velPID_I_Value                  matlab.ui.control.NumericEditField
        velPID_D_Value                  matlab.ui.control.NumericEditField
        velPID_shiftfactor_Value        matlab.ui.control.NumericEditField
        CurrentLimitsPanel              matlab.ui.container.Panel
        NominalmALabel                  matlab.ui.control.Label
        OverloadmALabel                 matlab.ui.control.Label
        PeakmALabel                     matlab.ui.control.Label
        nominal__mA__Value              matlab.ui.control.NumericEditField
        overload__mA__Value             matlab.ui.control.NumericEditField
        peak__mA__Value                 matlab.ui.control.NumericEditField
        AllComponentsTab                matlab.ui.container.Tab
        mode_bfr_conv                   matlab.ui.control.EditField
        mode_bfr_conv_Label             matlab.ui.control.Label
        send_polling_message_Value      slrealtime.ui.control.MomentaryButton
        StreamingLabel                  matlab.ui.control.Label
        send_streaming_message_Value    matlab.ui.control.Switch
        AutoConfigPanel                 matlab.ui.container.Panel
        STOP_Value                      slrealtime.ui.control.MomentaryButton
        RUN_Value                       slrealtime.ui.control.MomentaryButton
        auto_command_Value              slrealtime.ui.control.MomentaryButton
        current                         matlab.ui.scope.TimeScope
        Vq                              matlab.ui.scope.TimeScope
        ControlModeEditFieldLabel       matlab.ui.control.Label
        AMCBLDC_Control_mode_Value      matlab.ui.control.DropDown
        manual_commmand_Value           matlab.ui.container.ButtonGroup
        manual_commmand_Value_Button_SetIdle_1  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_SetMode_2  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_SetTarget_3  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_SetLimits_4  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_Discover_5  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_SetCurrentPID_6  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_SetSpeedPID_7  matlab.ui.control.RadioButton
        manual_commmand_Value_Button_SetConfig_8  matlab.ui.control.RadioButton
        TargetPanel                     matlab.ui.container.Panel
        target_Value_3                  matlab.ui.control.NumericEditField
        target_Value_2                  matlab.ui.control.NumericEditField
        target_Value                    matlab.ui.control.NumericEditField
        OpenLoopLabel                   matlab.ui.control.Label
        CurrentmALabel                  matlab.ui.control.Label
        SpeedVoltagedegsLabel           matlab.ui.control.Label
        SignalsTab                      matlab.ui.container.Tab
        position                        matlab.ui.scope.TimeScope
        Vq_3                            matlab.ui.scope.TimeScope
        velocity                        matlab.ui.scope.TimeScope
        current_3                       matlab.ui.scope.TimeScope
        StatusBarPanel                  matlab.ui.container.Panel
        StatusBarGrid                   matlab.ui.container.GridLayout
        StatusBar                       slrealtime.ui.control.StatusBar
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    methods (Static, Access = private)
        function out_val = convToDouble(in_val)
            if isnumeric(in_val) || islogical(in_val)
                out_val = double(in_val);
            else
                out_val = str2double(in_val);
            end
        end
        
        function out_val = convToString(in_val)
            out_val = string(MCControlModes(in_val));
        end
        
        function out_val = convToScalarLogical(in_val)
            if isnumeric(in_val)
                out_val = any(logical(in_val));
            else
                out_val = any(logical(str2double(in_val)));
            end
        end

        function out_val = convertLabelToValue(in_val, values, labels)
            out_val = values(strcmp(in_val, labels));
        end


        function out_val = convertValueToLabel(in_val, values, labels)
            out_val = labels(arrayfun(@(x)isequal(in_val, x), values));
        end

        function out_val = convertLabelToValue2(in_val, values, labels)
            idx = strcmp(in_val, labels);
            out_val = values(idx);
        end


        function out_val = convertValueToLabel2(in_val, values, labels)
            idx = find(arrayfun(@(x) isequal(in_val, x), values), 1);
            out_val = labels{idx};
        end


        function out_val = convertValueToValue(in_val, in_values, out_values)
            out_val = out_values(arrayfun(@(x)isequal(in_val, x), in_values));
        end
        
        
    end

    methods
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            targetSelector = app.TargetSelector;

            app.ConnectButton.TargetSource = targetSelector;
            app.LoadButton.TargetSource = targetSelector;
            app.StartStopButton.TargetSource = targetSelector;
            app.StopTimeEditField.TargetSource = targetSelector;
            app.SystemLog.TargetSource = targetSelector;
            app.StatusBar.TargetSource = targetSelector;

            app.send_polling_message_Value.TargetSource = targetSelector;


            app.send_polling_message_Value.BlockPath = 'gui_experiment_supervisor/CAN Communication/send polling message';
            app.send_polling_message_Value.ParameterName = 'Value';
            app.send_polling_message_Value.OnValue = 1;

            send_streaming_message_Value_values = [0 1];
            send_streaming_message_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.send_streaming_message_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/send streaming message';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val, send_streaming_message_Value_values, send_streaming_message_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val, send_streaming_message_Value_labels, send_streaming_message_Value_values);

            manual_commmand_Value_values = [1 2 3 4 5 6 7 8];
            manual_commmand_Value_labels = {'Set Idle','Set Control Mode','Set Target','Set Limits','Discover','Set Current PID','Set Speed PID','Set Motor Config'};
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.manual_commmand_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/manual commmand';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToLabel(val, manual_commmand_Value_values, manual_commmand_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertLabelToValue(val, manual_commmand_Value_values, manual_commmand_Value_labels);


            AMCBLDC_Control_mode_Value_values = [MCControlModes.Idle, MCControlModes.OpenLoop, MCControlModes.SpeedVoltage, MCControlModes.SpeedCurrent, MCControlModes.Current, MCControlModes.NotConfigured, MCControlModes.HWFault];
            AMCBLDC_Control_mode_Value_labels = {'Idle','OpenLoop','SpeedVoltage','SpeedCurrent','Current','NotConfigured','HWFault'};
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.AMCBLDC_Control_mode_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/AMCBLDC Control mode';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToLabel2(val,AMCBLDC_Control_mode_Value_values, AMCBLDC_Control_mode_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertLabelToValue2(val, AMCBLDC_Control_mode_Value_values, AMCBLDC_Control_mode_Value_labels);
            app.auto_command_Value.TargetSource = targetSelector;

            hInst = slrealtime.Instrument();


            app.auto_command_Value.BlockPath = 'gui_experiment_supervisor/CAN Communication/auto-manual state machine/auto command';
            app.auto_command_Value.ParameterName = 'Value';
            app.auto_command_Value.OnValue = 1;

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            hInst = slrealtime.Instrument();


            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.peak__mA__Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/CAN Sender/Switch Case Action Subsystem5/peak (mA)';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            hInst = slrealtime.Instrument();


            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.CurPID_shiftfactor_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/CurPID_shiftfactor';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.curPID_I_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/curPID_I';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.curPID_OutMax_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/curPID_OutMax';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.curPID_OutMin_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/curPID_OutMin';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.curPID_P_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/curPID_P';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.velPID_D_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/velPID_D';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.velPID_I_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/velPID_I';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.velPID_OutMax_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/velPID_OutMax';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.velPID_OutMin_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/velPID_OutMin ';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.velPID_P_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/velPID_P';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.velPID_shiftfactor_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/velPID_shiftfactor';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.nominal__mA__Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/CAN Sender/Switch Case Action Subsystem5/nominal (mA)';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.overload__mA__Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/CAN Sender/Switch Case Action Subsystem5/overload (mA)';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            hInst = slrealtime.Instrument();


            enable_verbosity_Value_values = [0 1];
            enable_verbosity_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.enable_verbosity_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/enable_verbosity';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val, enable_verbosity_Value_values, enable_verbosity_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val, enable_verbosity_Value_labels, enable_verbosity_Value_values);

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.encoder_tolerance_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/encoder_tolerance';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

      
            has_hall_sens_Value_values = [0 1];  
            has_hall_sens_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            disp(app.has_hall_sens_Value.Value);
            slrtcomp.Component = app.has_hall_sens_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/has_hall_sens';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val,has_hall_sens_Value_values,has_hall_sens_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val,has_hall_sens_Value_labels,has_hall_sens_Value_values);

            has_quadrature_encoder_Value_values = [0 1];
            has_quadrature_encoder_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.has_quadrature_encoder_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/has_quadrature_encoder';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val, has_quadrature_encoder_Value_values, has_quadrature_encoder_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val, has_quadrature_encoder_Value_labels, has_quadrature_encoder_Value_values);

            has_temperature_sens_Value_values = [0 1];
            has_temperature_sens_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.has_temperature_sens_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/has_temperature_sens';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val, has_temperature_sens_Value_values, has_temperature_sens_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val, has_temperature_sens_Value_labels, has_temperature_sens_Value_values);

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.pole_pairs_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/pole_pairs';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.rotor_encoder_resolution_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/rotor_encoder_resolution';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.rotor_index_offset_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/rotor_index_offset';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            use_index_Value_values = [0 1];
            use_index_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.use_index_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/use_index';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val, use_index_Value_values, use_index_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val, use_index_Value_labels, use_index_Value_values);

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/target';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            hInst = slrealtime.Instrument();


            enable_verbosity_Value_values = [0 1];
            enable_verbosity_Value_labels = [0 1];
            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.enable_verbosity_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/Faulhaber BXTH2214/enable_verbosity';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @(val)app.convertValueToValue(val, enable_verbosity_Value_values, enable_verbosity_Value_labels);
            slrtcomp.ConvertToTarget = @(val)app.convertValueToValue(val, enable_verbosity_Value_labels, enable_verbosity_Value_values);

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            hInst = slrealtime.Instrument();


            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.target_Value;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/target';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.target_Value_2;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/target';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.target_Value_3;
            slrtcomp.BlockPath = 'gui_experiment_supervisor/CAN Communication/target';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            hInst = slrealtime.Instrument();


            app.mode_bfr_conv.Value = 'mode_bfr_conv';
            app.mode_bfr_conv.Editable = false;

            hInst.connectScalar(app.mode_bfr_conv, 'mode_bfr_conv', 'Callback', @(t,d)app.convToString(d));

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;


            hInst = slrealtime.Instrument();


            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;
            app.RUN_Value.TargetSource = targetSelector;


            app.RUN_Value.BlockPath = 'gui_experiment_supervisor/CAN Communication/auto-manual state machine/RUN';
            app.RUN_Value.ParameterName = 'Value';
            app.RUN_Value.OnValue = 1;

            hInst = slrealtime.Instrument();


            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;
            app.STOP_Value.TargetSource = targetSelector;


            app.STOP_Value.BlockPath = 'gui_experiment_supervisor/CAN Communication/auto-manual state machine/STOP';
            app.STOP_Value.ParameterName = 'Value';
            app.STOP_Value.OnValue = 1;


        end

        % Value changed function: AMCBLDC_Control_mode_Value
        function AMCBLDC_Control_mode_ValueValueChanged(app, event)
            value = app.AMCBLDC_Control_mode_Value.Value;
            if(isequal(value,'OpenLoop'))
                app.target_Value.Visible ="off";
                app.target_Value_2.Visible ="on";
                app.target_Value_3.Visible="off";

                app.OpenLoopLabel.Visible ="on";
                app.CurrentmALabel.Visible ="off";
                app.SpeedVoltagedegsLabel.Visible = "off";
            else 
                if (isequal(value,'Current'))
                    app.target_Value.Visible ="off";
                    app.target_Value_2.Visible="off";
                    app.target_Value_3.Visible="on";

                    app.OpenLoopLabel.Visible ="off";
                    app.CurrentmALabel.Visible ="on";
                    app.SpeedVoltagedegsLabel.Visible = "off";
                end
                if (isequal(value,'SpeedVoltage'))
                    app.target_Value.Visible ="on";
                    app.target_Value_2.Visible="off";
                    app.target_Value_3.Visible ="off";

                    app.OpenLoopLabel.Visible ="off";
                    app.CurrentmALabel.Visible ="off";
                    app.SpeedVoltagedegsLabel.Visible = "on";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigureGrid = uigridlayout(app.UIFigure);
            app.StatusBarPanel = uipanel(app.UIFigureGrid);
            app.StatusBarGrid = uigridlayout(app.StatusBarPanel);
            app.StatusBar = slrealtime.ui.control.StatusBar(app.StatusBarGrid);
            app.TabGroup = uitabgroup(app.UIFigureGrid);
            app.CANParametersTab = uitab(app.TabGroup);
            app.CANParametersTab.Title = 'CAN Parameters';
            app.CurrentLimitsPanel = uipanel(app.CANParametersTab);
            app.CurrentLimitsPanel.Title = 'Current Limits';
            app.peak__mA__Value = uieditfield(app.CurrentLimitsPanel, 'numeric');
            app.peak__mA__Value.Limits = [-32768 32767];
            app.overload__mA__Value = uieditfield(app.CurrentLimitsPanel, 'numeric');
            app.overload__mA__Value.Limits = [-32768 32767];
            app.nominal__mA__Value = uieditfield(app.CurrentLimitsPanel, 'numeric');
            app.nominal__mA__Value.Limits = [-32768 32767];
            app.PeakmALabel = uilabel(app.CurrentLimitsPanel);
            app.PeakmALabel.Text = 'Peak (mA)';
            app.OverloadmALabel = uilabel(app.CurrentLimitsPanel);
            app.OverloadmALabel.Text = 'Overload (mA)';
            app.NominalmALabel = uilabel(app.CurrentLimitsPanel);
            app.NominalmALabel.Text = 'Nominal (mA)';
            app.VelocityPIDPanel = uipanel(app.CANParametersTab);
            app.VelocityPIDPanel.Title = 'Velocity PID';
            app.velPID_shiftfactor_Value = uieditfield(app.VelocityPIDPanel, 'numeric');
            app.velPID_shiftfactor_Value.Value = 1;
            app.velPID_D_Value = uieditfield(app.VelocityPIDPanel, 'numeric');
            app.velPID_I_Value = uieditfield(app.VelocityPIDPanel, 'numeric');
            app.velPID_P_Value = uieditfield(app.VelocityPIDPanel, 'numeric');
            app.velPID_OutMin_Value = uieditfield(app.VelocityPIDPanel, 'numeric');
            app.velPID_OutMax_Value = uieditfield(app.VelocityPIDPanel, 'numeric');
            app.OutMinLabel = uilabel(app.VelocityPIDPanel);
            app.OutMinLabel.Text = 'Out Min';
            app.OutMaxLabel = uilabel(app.VelocityPIDPanel);
            app.OutMaxLabel.Text = 'Out Max';
            app.ShiftFactorLabel = uilabel(app.VelocityPIDPanel);
            app.ShiftFactorLabel.Text = 'Shift Factor';
            app.KPLabel = uilabel(app.VelocityPIDPanel);
            app.KPLabel.Text = 'KP';
            app.KILabel = uilabel(app.VelocityPIDPanel);
            app.KILabel.Text = 'KI';
            app.KDLabel = uilabel(app.VelocityPIDPanel);
            app.KDLabel.Text = 'KD';
            app.CurrentPIDPanel = uipanel(app.CANParametersTab);
            app.CurrentPIDPanel.Title = 'Current PID';
            app.curPID_OutMin_Value = uieditfield(app.CurrentPIDPanel, 'numeric');
            app.curPID_OutMax_Value = uieditfield(app.CurrentPIDPanel, 'numeric');
            app.curPID_P_Value = uieditfield(app.CurrentPIDPanel, 'numeric');
            app.curPID_I_Value = uieditfield(app.CurrentPIDPanel, 'numeric');
            app.CurPID_shiftfactor_Value = uieditfield(app.CurrentPIDPanel, 'numeric');
            app.OutMinLabel_2 = uilabel(app.CurrentPIDPanel);
            app.OutMinLabel_2.Text = 'Out Min';
            app.OutMaxLabel_2 = uilabel(app.CurrentPIDPanel);
            app.OutMaxLabel_2.Text = 'Out Max';
            app.KPLabel_2 = uilabel(app.CurrentPIDPanel);
            app.KPLabel_2.Text = 'KP';
            app.KILabel_2 = uilabel(app.CurrentPIDPanel);
            app.KILabel_2.Text = 'KI';
            app.KDLabel_2 = uilabel(app.CurrentPIDPanel);
            app.KDLabel_2.Text = 'KD';
            app.MotorConfigurationPanel = uipanel(app.CANParametersTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.has_hall_sens_Value = uicheckbox(app.MotorConfigurationPanel);
            app.has_hall_sens_Value.Text = 'Has hall sens';
            app.has_quadrature_encoder_Value = uicheckbox(app.MotorConfigurationPanel);
            app.has_quadrature_encoder_Value.Text = 'Has quadrature encoder';
            app.has_temperature_sens_Value = uicheckbox(app.MotorConfigurationPanel);
            app.has_temperature_sens_Value.Text = 'Has temperature sens';
            app.use_index_Value = uicheckbox(app.MotorConfigurationPanel);
            app.use_index_Value.Text = 'Use index';
            app.enable_verbosity_Value = uicheckbox(app.MotorConfigurationPanel);
            app.enable_verbosity_Value.Text = 'Enable verbosity';
            app.encoder_tolerance_Value = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.rotor_index_offset_Value = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.pole_pairs_Value = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.rotor_encoder_resolution_Value = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.EncodertolLabel = uilabel(app.MotorConfigurationPanel);
            app.EncodertolLabel.Text = 'Encoder tol';
            app.EncoderresLabel = uilabel(app.MotorConfigurationPanel);
            app.EncoderresLabel.Text = 'Encoder res';
            app.RotorindexoffsetLabel = uilabel(app.MotorConfigurationPanel);
            app.RotorindexoffsetLabel.Text = 'Rotor index offset ';
            app.PolepairsLabel = uilabel(app.MotorConfigurationPanel);
            app.PolepairsLabel.Text = 'Pole pairs';
            app.AllComponentsTab = uitab(app.TabGroup);
            app.AllComponentsTab.Title = 'Controls';
            app.TargetPanel = uipanel(app.AllComponentsTab);
            app.TargetPanel.Title = 'Target';
            app.SpeedVoltagedegsLabel = uilabel(app.TargetPanel);
            app.SpeedVoltagedegsLabel.Text = ' Speed Voltage (deg/s)';
            app.CurrentmALabel = uilabel(app.TargetPanel);
            app.CurrentmALabel.Text = ' Current (mA)';
            app.OpenLoopLabel = uilabel(app.TargetPanel);
            app.OpenLoopLabel.Text = ' Open Loop (%)';
            app.target_Value = uieditfield(app.TargetPanel, 'numeric');
            app.target_Value_2 = uieditfield(app.TargetPanel, 'numeric');
            app.target_Value_3 = uieditfield(app.TargetPanel, 'numeric');
            app.manual_commmand_Value = uibuttongroup(app.AllComponentsTab);
            app.manual_commmand_Value.Title = 'Manual Command';
            app.manual_commmand_Value_Button_SetConfig_8 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetConfig_8.Text = 'Set Motor Config';
            app.manual_commmand_Value_Button_SetSpeedPID_7 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetSpeedPID_7.Text = 'Set Speed PID';
            app.manual_commmand_Value_Button_SetCurrentPID_6 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetCurrentPID_6.Text = 'Set Current PID';
            app.manual_commmand_Value_Button_Discover_5 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_Discover_5.Text = 'Discover';
            app.manual_commmand_Value_Button_SetLimits_4 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetLimits_4.Text = 'Set Limits';
            app.manual_commmand_Value_Button_SetTarget_3 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetTarget_3.Text = 'Set Target';
            app.manual_commmand_Value_Button_SetMode_2 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetMode_2.Text = 'Set Control Mode';
            app.manual_commmand_Value_Button_SetIdle_1 = uiradiobutton(app.manual_commmand_Value);
            app.manual_commmand_Value_Button_SetIdle_1.Text = 'Set Idle';
            app.manual_commmand_Value_Button_SetIdle_1.Value = true;
            app.AMCBLDC_Control_mode_Value = uidropdown(app.AllComponentsTab);
            app.AMCBLDC_Control_mode_Value.Items = {'OpenLoop', 'SpeedVoltage', 'Current'};
            app.AMCBLDC_Control_mode_Value.ValueChangedFcn = createCallbackFcn(app, @AMCBLDC_Control_mode_ValueValueChanged, true);
            app.AMCBLDC_Control_mode_Value.Value = 'OpenLoop';
            app.ControlModeEditFieldLabel = uilabel(app.AllComponentsTab);
            app.ControlModeEditFieldLabel.Text = 'Control Mode';
            app.Vq = uitimescope(app.AllComponentsTab);
            app.Vq.LegendNames = {'CAN Communication/CAN Receiver:5'};
            app.Vq.Title = 'Vq';
            app.Vq.XLabel = 'Ticks';
            app.Vq.YLabel = 'Amplitude (%)';
            app.Vq.XTimeSpan = 50;
            app.Vq.XTimeUnits = 'none';
            app.current = uitimescope(app.AllComponentsTab);
            app.current.LegendNames = {'current'};
            app.current.Title = 'current';
            app.current.XLabel = 'Ticks';
            app.current.YLabel = 'Amplitude (A)';
            app.current.XTimeSpan = 50;
            app.current.XTimeUnits = 'none';
            app.AutoConfigPanel = uipanel(app.AllComponentsTab);
            app.AutoConfigPanel.Title = 'Auto Config';
            app.auto_command_Value = slrealtime.ui.control.MomentaryButton(app.AutoConfigPanel);
            app.auto_command_Value.Text = 'Launch Auto Config';
            app.RUN_Value = slrealtime.ui.control.MomentaryButton(app.AutoConfigPanel);
            app.RUN_Value.Text = 'Run';
            app.STOP_Value = slrealtime.ui.control.MomentaryButton(app.AutoConfigPanel);
            app.STOP_Value.Text = 'Stop';
            app.send_streaming_message_Value = uiswitch(app.AllComponentsTab, 'slider');
            app.send_streaming_message_Value.ItemsData = [0 1];
            app.send_streaming_message_Value.Value = 0;
            app.StreamingLabel = uilabel(app.AllComponentsTab);
            app.StreamingLabel.Text = 'Streaming';
            app.send_polling_message_Value = slrealtime.ui.control.MomentaryButton(app.AllComponentsTab);
            app.send_polling_message_Value.Text = 'Send';
            app.mode_bfr_conv_Label = uilabel(app.AllComponentsTab);
            app.mode_bfr_conv_Label.Text = 'Status';
            app.mode_bfr_conv = uieditfield(app.AllComponentsTab, 'text');
            app.SignalsTab = uitab(app.TabGroup);
            app.SignalsTab.Title = 'Signals';
            app.current_3 = uitimescope(app.SignalsTab);
            app.current_3.LegendNames = {'current'};
            app.current_3.Title = 'current';
            app.current_3.XLabel = 'Ticks';
            app.current_3.YLabel = 'Amplitude (A)';
            app.current_3.XTimeSpan = 50;
            app.current_3.XTimeUnits = 'none';
            app.velocity = uitimescope(app.SignalsTab);
            app.velocity.LegendNames = {'CAN Communication/CAN Receiver:3'};
            app.velocity.Title = 'velocity';
            app.velocity.XLabel = 'Ticks';
            app.velocity.YLabel = 'Amplitude (deg/s)';
            app.velocity.XTimeSpan = 50;
            app.velocity.XTimeUnits = 'none';
            app.Vq_3 = uitimescope(app.SignalsTab);
            app.Vq_3.LegendNames = {'CAN Communication/CAN Receiver:5'};
            app.Vq_3.Title = 'Vq';
            app.Vq_3.XLabel = 'Ticks';
            app.Vq_3.YLabel = 'Amplitude (%)';
            app.Vq_3.XTimeSpan = 50;
            app.Vq_3.XTimeUnits = 'none';
            app.position = uitimescope(app.SignalsTab);
            app.position.LegendNames = {'CAN Communication/CAN Receiver:4'};
            app.position.Title = 'position';
            app.position.XLabel = 'Ticks';
            app.position.YLabel = 'Amplitude (rad)';
            app.position.XTimeSpan = 50;
            app.position.XTimeUnits = 'none';
            app.ToolstripPanel = uipanel(app.UIFigureGrid);
            app.ConnectToTarget = uipanel(app.ToolstripPanel);
            app.ConnectToTargetGrid = uigridlayout(app.ConnectToTarget);
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.ConnectToTargetGrid);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.ConnectToTargetGrid);
            app.Prepare = uipanel(app.ToolstripPanel);
            app.LoadButton = slrealtime.ui.control.LoadButton(app.Prepare);
            app.LoadButton.Application = 'gui_experiment_supervisor';
            app.SystemLogPanel = uipanel(app.ToolstripPanel);
            app.SystemLog = slrealtime.ui.control.SystemLog(app.SystemLogPanel);
            app.RunOnTarget = uipanel(app.ToolstripPanel);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.RunOnTarget);
            app.StopTimeLabel = uilabel(app.RunOnTarget);
            app.StopTimeLabel.Text = 'Stop Time';
            app.StopTimeEditField = slrealtime.ui.control.StopTimeEditField(app.RunOnTarget);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CAN Communication/CAN Receiver:2'], app.current_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CAN Communication/CAN Receiver:2'], app.current);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CAN Communication/CAN Receiver:3'], app.velocity);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CAN Communication/CAN Receiver:4'], app.position);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CAN Communication/CAN Receiver:5'], app.Vq_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CAN Communication/CAN Receiver:5'], app.Vq);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMCFOC7_app

            % Associate the Simulink Model
            app.Simulation = simulation('gui_experiment_supervisor');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end